classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        NewMenu                      matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        Toolbar                      matlab.ui.container.Toolbar
        PushTool                     matlab.ui.container.toolbar.PushTool
        PushTool2                    matlab.ui.container.toolbar.PushTool
        TabGroup                     matlab.ui.container.TabGroup
        Output1and2and3Tab           matlab.ui.container.Tab
        MeasurementsPanel            matlab.ui.container.Panel
        UITable                      matlab.ui.control.Table
        EnterButton                  matlab.ui.control.Button
        dmEditField                  matlab.ui.control.NumericEditField
        dmEditFieldLabel             matlab.ui.control.Label
        PowerUnitsDropDown           matlab.ui.control.DropDown
        PowerUnitsDropDownLabel      matlab.ui.control.Label
        OutputsPanel                 matlab.ui.container.Panel
        ReceivedPowerEditField       matlab.ui.control.NumericEditField
        ReceivedPowerEditFieldLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        ModelParametersPanel         matlab.ui.container.Panel
        PrdoEditField                matlab.ui.control.NumericEditField
        PrdoLabel                    matlab.ui.control.Label
        dDropDown                    matlab.ui.control.DropDown
        dDropDownLabel               matlab.ui.control.Label
        SamplesEditField             matlab.ui.control.NumericEditField
        SamplesEditFieldLabel        matlab.ui.control.Label
        EditField                    matlab.ui.control.NumericEditField
        EditFieldLabel               matlab.ui.control.Label
        nEditField                   matlab.ui.control.NumericEditField
        nEditFieldLabel              matlab.ui.control.Label
        domEditField                 matlab.ui.control.NumericEditField
        domEditFieldLabel            matlab.ui.control.Label
        Output4Tab                   matlab.ui.container.Tab
        ExtraParametersPanel         matlab.ui.container.Panel
        TimeEditField                matlab.ui.control.NumericEditField
        TimeEditFieldLabel           matlab.ui.control.Label
        RequiredPREditField          matlab.ui.control.NumericEditField
        RequiredPREditFieldLabel     matlab.ui.control.Label
        AntennaParametersPanel       matlab.ui.container.Panel
        FMHzEditField                matlab.ui.control.NumericEditField
        FMHzEditFieldLabel           matlab.ui.control.Label
        GrEditField                  matlab.ui.control.NumericEditField
        GrEditFieldLabel             matlab.ui.control.Label
        GtEditField                  matlab.ui.control.NumericEditField
        GtEditFieldLabel             matlab.ui.control.Label
        PtEditField                  matlab.ui.control.NumericEditField
        PtEditFieldLabel             matlab.ui.control.Label
        CommandWindowLabel           matlab.ui.control.Label
        CommandWindowTextArea        matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        measurements_array % Description
        CallingApp % login App
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, password)
            app.CallingApp  = caller;
            
            % Process the password
            pw = passwordUI('Query', 'AlphaNum');
            % Counter for wrong pw entries
            WrongCount = 0;
            
            while strcmp(pw,password) == 0
                WrongCount = WrongCount + 1;
                if WrongCount == 3
                    delete(app)
                    break
                end
                msgbox("Wrong Password!","Error","custom",imread("bedo.png"));
                pw = passwordUI('Query', 'AlphaNum');
            end
            if strcmp(pw,password) == 1
                
                f = msgbox("Welcome Back");
            end
            
            
            %app.d_array = {" "};
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
              %Read Json File
              file_json = uigetfile({'*.json'},'Select Input Parameters');
              file = jsondecode(fileread(file_json));  
              fields = fieldnames(file.ModelParameters.Measurements);
              
              table_row = {};
              table_data = [];
              
              temp = fields;
              
              corrupted_file = 0;
              app.domEditField.Value = file.ModelParameters.do;  
              
              %Parse Values
              for counter = 1 : 1 : length(fields)
            
                   table_row = {};        
                   fields{counter}(1) = " ";
                   x = str2num(fields{counter}(1,:));

                   if x  < app.domEditField.Value
                       
                       %Reset All Line Edits Values and Color Font
                       cla(app.UIAxes) 
                       app.PrdoEditField.Value  = 0;
                       app.UITable.Data = [];
                       app.nEditField.Value     = 0;
                       app.EditField.Value      = 0;          
                       app.SamplesEditField.Value  = 0;                          
                       app.CommandWindowTextArea.FontColor = [0.00,0.00,0.00];
                       app.CommandWindowTextArea.Value = sprintf('[Obelos]: d = %d is smaller than do = %d \n[Obelos]: File is corrupted. Process is terminated ' ,x , app.domEditField.Value); 
                       corrupted_file =1 ;
%                        for counter2 = 1 : 1 : length(fields)
%                        app.dDropDown.Items(counter2) = [];
%                        end                         
                       return;
                   end    

                    fields_array(counter) = x;
                   app.measurements_array = [ app.measurements_array  getfield(file.ModelParameters.Measurements,temp{counter}) ];
                  
              end      

              app.PrdoEditField.Value  = file.ModelParameters.Pr_do;
              app.nEditField.Value     = file.ModelParameters.n;
              app.EditField.Value      = file.ModelParameters.x_;          
              app.SamplesEditField.Value  = file.ModelParameters.k;      

              app.dDropDown.Items = fields;         
                                     

              %Output on command window
              if corrupted_file == 0  
                  t= table(fields_array.',app.measurements_array.');
                  app.UITable.Data = t;
                  scatter(app.UIAxes,fields_array,app.measurements_array);
                  app.CommandWindowTextArea.Value = sprintf('[Obelos]: File is Imported Successfully!\n[Obelos]: Figure is Plotted');  
                  app.CommandWindowTextArea.FontColor = [0.47,0.67,0.19];  %Green
              end


        end

        % Clicked callback: PushTool2
        function PushTool2Clicked(app, event)

                file2.ModelParameters.d  = app.dmEditField.Value;
                file2.ModelParameters.do = app.domEditField.Value;
                file2.ModelParameters.n  = app.nEditField.Value;
                file2.ModelParameters.x_ = app.EditField.Value;          
                file2.ModelParameters.k  = app.SamplesEditField.Value;
                file2.ModelParameters.Measurnments = {};
                
                 
                file_json = jsonencode(file2,'PrettyPrint',true);
                
                fileID = fopen('obelos.json','w');
                fprintf(fileID,'%s',file_json);
                fclose(fileID);


              %Output on command window
              app.CommandWindowTextArea.Value = sprintf('[Obelos]: File is Exported Successfully!');  
              app.CommandWindowTextArea.FontColor = [0.47,0.67,0.19];
        end

        % Value changed function: PowerUnitsDropDown
        function PowerUnitsDropDownValueChanged(app, event)
            value = app.PowerUnitsDropDown.Value;
            
            app.CommandWindowTextArea.Value = sprintf('[Obelos]: Power Units are %s',value); 
        end

        % Value changed function: ReceivedPowerEditField
        function ReceivedPowerEditFieldValueChanged(app, event)
            value = app.ReceivedPowerEditField.Value;
            
        end

        % Callback function
        function SubmitButtonPushed(app, event)
            powerunits = app.PowerUnitsDropDown.Value;
                
            temp = app.CommandWindowTextArea.Value;
            
            
            if strcmp(powerunits,"dB")
                
             app.ReceivedPowerEditField.Value = 0;
                      
           
             app.CommandWindowTextArea.Value = sprintf('[Obelos]: Received Power is Calculated %d dB',app.ReceivedPowerEditField.Value); 


            else
                
            Pr_do_decimal = app.PtEditField.Value *  app.GtEditField.Value * app.GrEditField.Value * ( (300000000 / (app.FMHzEditField.Value*10^6) ) / (4 * pi * app.domEditField.Value ) ) ^ 2;
                
            Pr_d_decimal =  Pr_do_decimal * (app.domEditField.Value / app.dmEditField.Value ) ^  app.nEditField.Value ;
            
            PL_do_decimal = app.PtEditField.Value / Pr_do_decimal ;
            
            Mean = PL_do_decimal + 10 * app.nEditField.Value * log10(app.dmEditField.Value/app.domEditField.Value);
            
            PL_d_decimal = Mean + app.EditField.Value .* randn(app.SamplesEditField.Value,1) ;
            
            scatter(app.UIAxes,app.dmEditField.Value,PL_d_decimal);
            
            app.CommandWindowTextArea.Value = sprintf('[Obelos]: Figure is Plotted '); 


            app.UIAxes.Title.String = sprintf("Scatter Plot for n = %d" , app.nEditField.Value);                
                
             app.ReceivedPowerEditField.Value = Pr_d_decimal ; 
             
             app.CommandWindowTextArea.Value = sprintf("\n[Obelos]: Received Power is Calculated %d decimal",app.ReceivedPowerEditField.Value);
             
             app.CommandWindowTextArea.Value = strcat(temp,app.CommandWindowTextArea.Value);
             
            end
            
            
            %app.dmListBox.Items = {'100','200'};
            app.dDropDown.Items = {'100','200'};
            app.dDropDown.Value = '100';
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            if app.dmEditField.Value > app.domEditField.Value 
                
                app.d_array = {app.d_array, int2str(app.dmEditField.Value) };
                
                
            %    app.domEditField.Value = app.d_array(1);
                
                
                app.dmListBox.Items = cellstr(app.d_array);
                 
                app.CommandWindowTextArea.Value = sprintf("[Obelos]: Distance d %d is added ",app.dmEditField.Value);
                
            else
                app.CommandWindowTextArea.Value = sprintf("[Obelos]: Distance d %s is less than do ",app.dmEditField.Value);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % reEnable the button in login app
            %app.CallingApp.CreateButton.Enable = "on";
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Checked = 'on';
            app.NewMenu.Text = 'New';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Import Parameters'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = 'Capture.PNG';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Export Parameters'};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = 'Capture2.PNG';
            app.CommandWindowTextArea = uitextarea(app.UIFigure);
            app.CommandWindowTextArea.Editable = 'off';
            app.CommandWindowTextArea.Placeholder = 'Welcome to Obelos!';
            app.CommandWindowLabel = uilabel(app.UIFigure);
            app.CommandWindowLabel.Text = 'Command Window';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Output1and2and3Tab = uitab(app.TabGroup);
            app.Output1and2and3Tab.Title = 'Output 1 and 2 and 3';
            app.ModelParametersPanel = uipanel(app.Output1and2and3Tab);
            app.ModelParametersPanel.Title = 'Model Parameters';
            app.domEditFieldLabel = uilabel(app.ModelParametersPanel);
            app.domEditFieldLabel.Text = 'do (m)';
            app.domEditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.nEditFieldLabel = uilabel(app.ModelParametersPanel);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.EditFieldLabel = uilabel(app.ModelParametersPanel);
            app.EditFieldLabel.Text = 'σ';
            app.EditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.SamplesEditFieldLabel = uilabel(app.ModelParametersPanel);
            app.SamplesEditFieldLabel.Text = 'Samples';
            app.SamplesEditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.dDropDownLabel = uilabel(app.ModelParametersPanel);
            app.dDropDownLabel.Text = 'd';
            app.dDropDown = uidropdown(app.ModelParametersPanel);
            app.dDropDown.Items = {};
            app.dDropDown.Value = {};
            app.PrdoLabel = uilabel(app.ModelParametersPanel);
            app.PrdoLabel.Text = 'Pr (do)';
            app.PrdoEditField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.OutputsPanel = uipanel(app.Output1and2and3Tab);
            app.OutputsPanel.Title = 'Outputs';
            app.UIAxes = uiaxes(app.OutputsPanel);
            xlabel(app.UIAxes, 'distance d (m)')
            ylabel(app.UIAxes, 'Path Loss')
            zlabel(app.UIAxes, 'Z')
            app.ReceivedPowerEditFieldLabel = uilabel(app.OutputsPanel);
            app.ReceivedPowerEditFieldLabel.Text = 'Received Power';
            app.ReceivedPowerEditField = uieditfield(app.OutputsPanel, 'numeric');
            app.ReceivedPowerEditField.ValueChangedFcn = createCallbackFcn(app, @ReceivedPowerEditFieldValueChanged, true);
            app.PowerUnitsDropDownLabel = uilabel(app.Output1and2and3Tab);
            app.PowerUnitsDropDownLabel.Text = 'Power Units';
            app.PowerUnitsDropDown = uidropdown(app.Output1and2and3Tab);
            app.PowerUnitsDropDown.Items = {'decimal', 'dB'};
            app.PowerUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @PowerUnitsDropDownValueChanged, true);
            app.PowerUnitsDropDown.Value = 'decimal';
            app.dmEditFieldLabel = uilabel(app.Output1and2and3Tab);
            app.dmEditFieldLabel.Text = 'd (m)';
            app.dmEditField = uieditfield(app.Output1and2and3Tab, 'numeric');
            app.EnterButton = uibutton(app.Output1and2and3Tab, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.MeasurementsPanel = uipanel(app.Output1and2and3Tab);
            app.MeasurementsPanel.Title = 'Measurements';
            app.UITable = uitable(app.MeasurementsPanel);
            app.UITable.ColumnName = {'Distance'; 'Power'};
            app.UITable.RowName = {};
            app.Output4Tab = uitab(app.TabGroup);
            app.Output4Tab.Title = 'Output 4';
            app.AntennaParametersPanel = uipanel(app.Output4Tab);
            app.AntennaParametersPanel.Title = 'Antenna Parameters';
            app.PtEditFieldLabel = uilabel(app.AntennaParametersPanel);
            app.PtEditFieldLabel.Text = 'Pt ';
            app.PtEditField = uieditfield(app.AntennaParametersPanel, 'numeric');
            app.GtEditFieldLabel = uilabel(app.AntennaParametersPanel);
            app.GtEditFieldLabel.Text = 'Gt';
            app.GtEditField = uieditfield(app.AntennaParametersPanel, 'numeric');
            app.GrEditFieldLabel = uilabel(app.AntennaParametersPanel);
            app.GrEditFieldLabel.Text = 'Gr';
            app.GrEditField = uieditfield(app.AntennaParametersPanel, 'numeric');
            app.FMHzEditFieldLabel = uilabel(app.AntennaParametersPanel);
            app.FMHzEditFieldLabel.Text = 'F (MHz)';
            app.FMHzEditField = uieditfield(app.AntennaParametersPanel, 'numeric');
            app.ExtraParametersPanel = uipanel(app.Output4Tab);
            app.ExtraParametersPanel.Title = 'Extra Parameters';
            app.RequiredPREditFieldLabel = uilabel(app.ExtraParametersPanel);
            app.RequiredPREditFieldLabel.Text = 'Required PR';
            app.RequiredPREditField = uieditfield(app.ExtraParametersPanel, 'numeric');
            app.TimeEditFieldLabel = uilabel(app.ExtraParametersPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.ExtraParametersPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end